classdef prog1935938 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Slider_2Label                 matlab.ui.control.Label
        six4                          matlab.ui.control.Slider
        kSlider_2Label                matlab.ui.control.Label
        twok                          matlab.ui.control.Slider
        Slider_3Label                 matlab.ui.control.Label
        one25                         matlab.ui.control.Slider
        kSlider_4Label                matlab.ui.control.Label
        eightk                        matlab.ui.control.Slider
        Slider_4Label                 matlab.ui.control.Label
        two50                         matlab.ui.control.Slider
        Slider_5Label                 matlab.ui.control.Label
        five00                        matlab.ui.control.Slider
        kSliderLabel                  matlab.ui.control.Label
        onek                          matlab.ui.control.Slider
        kSlider_3Label                matlab.ui.control.Label
        fourk                         matlab.ui.control.Slider
        kSlider_5Label                matlab.ui.control.Label
        sixteenk                      matlab.ui.control.Slider
        Label_2                       matlab.ui.control.Label
        three2                        matlab.ui.control.Slider
        DecibelGainLossLabel          matlab.ui.control.Label
        FrequencyLabel                matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        LoadUnfilteredandVolumedFileButton  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        PresetsListBoxLabel           matlab.ui.control.Label
        PresetsListBox                matlab.ui.control.ListBox
        MasterVolumeMultiplierSliderLabel  matlab.ui.control.Label
        MasterVolumeMultiplierSlider  matlab.ui.control.Slider
        AudiodurationLampLabel        matlab.ui.control.Label
        AudiodurationLamp             matlab.ui.control.Lamp
        Label                         matlab.ui.control.Label
        ApplyEqualiserEffectButton    matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    %Joseph Liu
    %C1935938
    
    methods (Access = private)
        
        function ls = lowshelving(~,x, Wc, G)
% Author: M. Holters
% Applies a low-frequency shelving filter to the input signal x.

%--------------------------------------------------------------------------
% All source code provided until 'End of reference' is provided without any warranties as published in 
% DAFX book 2nd edition, copyright Wiley & Sons 2011, available at 
% http://www.dafx.de. It may be used for educational purposes and not 
% for commercial applications without further permission.
%--------------------------------------------------------------------------

        V0 = 10^(G/20); H0 = V0 - 1;
        if G >= 0
          c = (tan(pi*Wc/2)-1) / (tan(pi*Wc/2)+1);     % boost
        else
          c = (tan(pi*Wc/2)-V0) / (tan(pi*Wc/2)+V0);   % cut
        end
        xh = 0;
        for n=1:length(x)
          xh_new = x(n) - c*xh;
          ap_y = c * xh_new + xh;
          xh = xh_new;
          ls(n) = 0.5 * H0 * (x(n) + ap_y) + x(n);  % change to minus for HS
        end
        end
        
        function pf = peakfilt(~,x,Wc,Wb,G)
        % Author: M. Holters
        % Applies a peak filter to the input signal x.
            V0 = 10^(G/20); H0 = V0 - 1;
            if G >= 0
                c = (tan(pi*Wb/2)-1) / (tan(pi*Wb/2)+1);     % boost
            else
                c = (tan(pi*Wb/2)-V0) / (tan(pi*Wb/2)+V0);   % cut
            end
            d = -cos(pi*Wc);
            xh = [0, 0];
            for n=1:length(x)
                xh_new = x(n) - d*(1-c)*xh(1) + c*xh(2);
                ap_y = -c * xh_new + d*(1-c)*xh(1) + xh(2);
                xh = [xh_new, xh(1)];
                pf(n) = 0.5 * H0 * (x(n) - ap_y) + x(n);
            end
        end
        function hs = highshelving(~,x, Wc, G)
            % Author: M. Holters
            % Applies a low-frequency shelving filter to the input signal x.
            V0 = 10^(G/20); H0 = V0 - 1;
            if G >= 0
              c = (tan(pi*Wc/2)-1) / (tan(pi*Wc/2)+1);     % boost
            else
              c = (tan(pi*Wc/2)-V0) / (tan(pi*Wc/2)+V0);   % cut
            end
            xh = 0;
            for n=1:length(x)
              xh_new = x(n) - c*xh;
              ap_y = c * xh_new + xh;
              xh = xh_new;
            hs(n) = 0.5 * H0 * (x(n) + ap_y) - x(n);  % change to plus for LS
            end
        end
% End of reference
    end
            

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadUnfilteredandVolumedFileButton
        function LoadUnfilteredandVolumedFileButtonPushed(app, event)
            cla(app.UIAxes);
            app.PresetsListBox.Value="Flat";
            app.AudiodurationLamp.Color = 'black'; %Black default lamp
            chosenfile=uigetfile({'*.wav';'*.mp3';'*.flac';'*.aac';'*.m4a'});
            ylim(app.UIAxes, [-5 5]);
            %Referenced from Yared Andarge
            [y,Fs]=audioread(chosenfile);
            t=(0:size(y,1)-1/Fs); %Time Vector
            soundduration=length(y)./Fs; %file duration
            volumenow=app.MasterVolumeMultiplierSlider.Value;
            plot(app.UIAxes,t,y*volumenow);
            if soundduration <= 0.5
                app.AudiodurationLamp.Color='r';
            elseif soundduration <= 2
                app.AudiodurationLamp.Color=[1.00,0.41,0.16];
            else
                app.AudiodurationLamp.Color='g';
            end
        end

        % Value changed function: MasterVolumeMultiplierSlider
        function MasterVolumeMultiplierSliderValueChanged(app, event)

        end

        % Value changed function: five00
        function five00ValueChanged(app, event)

        end

        % Value changed function: onek
        function onekValueChanged(app, event)

        end

        % Value changed function: two50
        function two50ValueChanged(app, event)

            
        end

        % Value changed function: six4
        function six4ValueChanged(app, event)

            
        end

        % Value changed function: twok
        function twokValueChanged(app, event)

            
        end

        % Value changed function: one25
        function one25ValueChanged(app, event)

            
        end

        % Value changed function: eightk
        function eightkValueChanged(app, event)

            
        end

        % Value changed function: fourk
        function fourkValueChanged(app, event)
            
        end

        % Value changed function: sixteenk
        function sixteenkValueChanged(app, event)

        end

        % Callback function
        function OverwritePresetButtonPushed(app, event)
       
        end

        % Button pushed function: ApplyEqualiserEffectButton
        function ApplyEqualiserEffectButtonPushed(app, event)
            cla(app.UIAxes);
            volumenow=app.MasterVolumeMultiplierSlider.Value;
            app.AudiodurationLamp.Color = 'black'; %Black default lamp
            chosenfile=uigetfile({'*.wav';'*.mp3';'*.flac';'*.aac';'*.m4a'});
            ylim(app.UIAxes, [-5 5]);
            
            %Referenced from Spyros Lontos, Arvita Agus Kurniasari and M Holters
            [y,Fs]=audioread(chosenfile);
            t=(0:size(y,1)-1/Fs); %Time Vector
            soundduration=length(y)./Fs; %file duration
            %Lowpass filter
            filter32=lowshelving(app,y,(2*32)/Fs,app.three2.Value);
            %Peak Filter
            filter64=peakfilt(app,filter32,(2*64)/Fs,(2*32)/Fs,app.six4.Value);
            filter125=peakfilt(app,filter64,(2*125)/Fs,(2*64)/Fs,app.one25.Value);
            filter250=peakfilt(app,filter125,(2*250)/Fs,(2*125)/Fs,app.two50.Value);
            filter500=peakfilt(app,filter250,(2*500)/Fs,(2*250)/Fs,app.five00.Value);
            filter1k=peakfilt(app,filter500,(2*1000)/Fs,(2*500)/Fs,app.onek.Value);
            filter2k=peakfilt(app,filter1k,(2*2000)/Fs,(2*1000)/Fs,app.twok.Value);
            filter4k=peakfilt(app,filter2k,(2*4000)/Fs,(2*2000)/Fs,app.fourk.Value);
            filter8k=peakfilt(app,filter4k,(2*8000)/Fs,(2*4000)/Fs,app.eightk.Value);
            %Highpass Filter
            filter16k=highshelving(app,filter8k,(2*16000)/Fs,app.sixteenk.Value);
            %Add effect
            audioequaliser=filter16k;
            
            yEqualised=volumenow*audioequaliser;
            plot(app.UIAxes,t,yEqualised);
            if soundduration <= 0.5
                app.AudiodurationLamp.Color='r';
            elseif soundduration <= 2
                app.AudiodurationLamp.Color=[1.00,0.41,0.16];
            else
                app.AudiodurationLamp.Color='g';
            end
        end

        % Value changed function: three2
        function three2ValueChanged(app, event)

        end

        % Value changed function: PresetsListBox
        function PresetsListBoxValueChanged(app, event)
            if (app.PresetsListBox.Value=="Flat")
                app.three2.Value=0;
                app.six4.Value=0;
                app.one25.Value=0;
                app.two50.Value=0;
                app.five00.Value=0;
                app.onek.Value=0;
                app.twok.Value=0;
                app.fourk.Value=0;
                app.eightk.Value=0;
                app.sixteenk.Value=0;
            elseif (app.PresetsListBox.Value=="Acoustic")
                app.three2.Value=4.5;
                app.six4.Value=4.5;
                app.one25.Value=4;
                app.two50.Value=1;
                app.five00.Value=2;
                app.onek.Value=2;
                app.twok.Value=3;
                app.fourk.Value=4;
                app.eightk.Value=3;
                app.sixteenk.Value=2;
            elseif (app.PresetsListBox.Value=="Rock")
                app.three2.Value=5;
                app.six4.Value=3;
                app.one25.Value=2;
                app.two50.Value=1;
                app.five00.Value=-1;
                app.onek.Value=-2;
                app.twok.Value=0;
                app.fourk.Value=2;
                app.eightk.Value=3;
                app.sixteenk.Value=4;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {208, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Slider_2Label = uilabel(app.LeftPanel);
            app.Slider_2Label.Text = '64';
            app.six4 = uislider(app.LeftPanel);
            app.six4.Limits = [-12 12];
            app.six4.ValueChangedFcn = createCallbackFcn(app, @six4ValueChanged, true);
            app.six4.MinorTicks = [-12 -11.2 -10.4 -9.6 -8.8 -8 -7.2 -6.4 -5.6 -4.8 -4 -3.2 -2.4 -1.6 -0.800000000000001 0 0.800000000000001 1.6 2.4 3.2 4 4.8 5.6 6.4 7.2 8 8.8 9.6 10.4 11.2 12];
            app.kSlider_2Label = uilabel(app.LeftPanel);
            app.kSlider_2Label.Text = '2k';
            app.twok = uislider(app.LeftPanel);
            app.twok.Limits = [-12 12];
            app.twok.ValueChangedFcn = createCallbackFcn(app, @twokValueChanged, true);
            app.Slider_3Label = uilabel(app.LeftPanel);
            app.Slider_3Label.Text = '125';
            app.one25 = uislider(app.LeftPanel);
            app.one25.Limits = [-12 12];
            app.one25.ValueChangedFcn = createCallbackFcn(app, @one25ValueChanged, true);
            app.kSlider_4Label = uilabel(app.LeftPanel);
            app.kSlider_4Label.Text = '8k';
            app.eightk = uislider(app.LeftPanel);
            app.eightk.Limits = [-12 12];
            app.eightk.ValueChangedFcn = createCallbackFcn(app, @eightkValueChanged, true);
            app.Slider_4Label = uilabel(app.LeftPanel);
            app.Slider_4Label.Text = '250';
            app.two50 = uislider(app.LeftPanel);
            app.two50.Limits = [-12 12];
            app.two50.ValueChangedFcn = createCallbackFcn(app, @two50ValueChanged, true);
            app.Slider_5Label = uilabel(app.LeftPanel);
            app.Slider_5Label.Text = '500';
            app.five00 = uislider(app.LeftPanel);
            app.five00.Limits = [-12 12];
            app.five00.ValueChangedFcn = createCallbackFcn(app, @five00ValueChanged, true);
            app.kSliderLabel = uilabel(app.LeftPanel);
            app.kSliderLabel.Text = '1k';
            app.onek = uislider(app.LeftPanel);
            app.onek.Limits = [-12 12];
            app.onek.ValueChangedFcn = createCallbackFcn(app, @onekValueChanged, true);
            app.kSlider_3Label = uilabel(app.LeftPanel);
            app.kSlider_3Label.Text = '4k';
            app.fourk = uislider(app.LeftPanel);
            app.fourk.Limits = [-12 12];
            app.fourk.ValueChangedFcn = createCallbackFcn(app, @fourkValueChanged, true);
            app.kSlider_5Label = uilabel(app.LeftPanel);
            app.kSlider_5Label.Text = '16k';
            app.sixteenk = uislider(app.LeftPanel);
            app.sixteenk.Limits = [-12 12];
            app.sixteenk.ValueChangedFcn = createCallbackFcn(app, @sixteenkValueChanged, true);
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '32';
            app.three2 = uislider(app.LeftPanel);
            app.three2.Limits = [-12 12];
            app.three2.ValueChangedFcn = createCallbackFcn(app, @three2ValueChanged, true);
            app.three2.MinorTicks = [-12 -11.2 -10.4 -9.6 -8.8 -8 -7.2 -6.4 -5.6 -4.8 -4 -3.2 -2.4 -1.6 -0.800000000000001 0 0.800000000000001 1.6 2.4 3.2 4 4.8 5.6 6.4 7.2 8 8.8 9.6 10.4 11.2 12];
            app.DecibelGainLossLabel = uilabel(app.LeftPanel);
            app.DecibelGainLossLabel.Text = 'Decibel Gain/Loss';
            app.FrequencyLabel = uilabel(app.LeftPanel);
            app.FrequencyLabel.Text = 'Frequency';
            app.RightPanel = uipanel(app.GridLayout);
            app.LoadUnfilteredandVolumedFileButton = uibutton(app.RightPanel, 'push');
            app.LoadUnfilteredandVolumedFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadUnfilteredandVolumedFileButtonPushed, true);
            app.LoadUnfilteredandVolumedFileButton.Text = 'Load Unfiltered and Volumed File';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Audio File')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [-5 -4.5 -4 -3.5 -3 -2.5 -2 -1.5 -1 -0.5 0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.UIAxes.YTickLabel = {'-5'; '-4.5'; '-4'; '-3.5'; '-3'; '-2.5'; '-2'; '-1.5'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'; '3.5'; '4'; '4.5'; '5'};
            app.PresetsListBoxLabel = uilabel(app.RightPanel);
            app.PresetsListBoxLabel.Text = 'Presets';
            app.PresetsListBox = uilistbox(app.RightPanel);
            app.PresetsListBox.Items = {'Flat', 'Acoustic', 'Rock'};
            app.PresetsListBox.ValueChangedFcn = createCallbackFcn(app, @PresetsListBoxValueChanged, true);
            app.PresetsListBox.Value = 'Flat';
            app.MasterVolumeMultiplierSliderLabel = uilabel(app.RightPanel);
            app.MasterVolumeMultiplierSliderLabel.Text = 'Master Volume(Multiplier)';
            app.MasterVolumeMultiplierSlider = uislider(app.RightPanel);
            app.MasterVolumeMultiplierSlider.Limits = [0 5];
            app.MasterVolumeMultiplierSlider.ValueChangedFcn = createCallbackFcn(app, @MasterVolumeMultiplierSliderValueChanged, true);
            app.MasterVolumeMultiplierSlider.Value = 1;
            app.AudiodurationLampLabel = uilabel(app.RightPanel);
            app.AudiodurationLampLabel.Text = 'Audio duration';
            app.AudiodurationLamp = uilamp(app.RightPanel);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = 'NOTE: You must select the same file when you click either button, also filtering requires pressing ''Apply Equaliser Effect''';
            app.ApplyEqualiserEffectButton = uibutton(app.RightPanel, 'push');
            app.ApplyEqualiserEffectButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyEqualiserEffectButtonPushed, true);
            app.ApplyEqualiserEffectButton.Text = 'Apply Equaliser Effect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prog1935938

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end